classdef Reflectarray_LaboratoryPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReflectarrayLaboratoryUIFigure  matlab.ui.Figure
        ReflectarrayParametersPanel     matlab.ui.container.Panel
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        ElementWidthmmEditFieldLabel    matlab.ui.control.Label
        ElementWidthmmEditField         matlab.ui.control.NumericEditField
        NumofElementEditFieldLabel      matlab.ui.control.Label
        NumofElementEditField           matlab.ui.control.NumericEditField
        ElementPhasePathButton          matlab.ui.control.Button
        ElementLibraryButton            matlab.ui.control.Button
        ThetaEditFieldLabel             matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        PhiEditFieldLabel               matlab.ui.control.Label
        PhiEditField                    matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        ReflectarrayCharacteristicsDropDownLabel  matlab.ui.control.Label
        ReflectarrayCharacteristicsDropDown  matlab.ui.control.DropDown
        DesignButton                    matlab.ui.control.Button
        FeedParametersPanel             matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        FeedLibraryButton               matlab.ui.control.Button
        qValueSliderLabel               matlab.ui.control.Label
        qValueSlider                    matlab.ui.control.Slider
        ApertureEfficiencyCalculatorPanel  matlab.ui.container.Panel
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        GaindBEditFieldLabel            matlab.ui.control.Label
        GaindBEditField                 matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        ApertureEfficiencyEditFieldLabel  matlab.ui.control.Label
        ApertureEfficiencyEditField     matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        SquarePatchTab                  matlab.ui.container.Tab
        GenerateButton_2                matlab.ui.control.Button
        LayerSquareTab                  matlab.ui.container.Tab
        K1EditField_2Label              matlab.ui.control.Label
        K1EditField_2                   matlab.ui.control.NumericEditField
        R2K1R1Label                     matlab.ui.control.Label
        GenerateButton_3                matlab.ui.control.Button
        DoubleCircularRingTab           matlab.ui.container.Tab
        K1EditFieldLabel                matlab.ui.control.Label
        K1EditField                     matlab.ui.control.NumericEditField
        K2EditFieldLabel                matlab.ui.control.Label
        K2EditField                     matlab.ui.control.NumericEditField
        WK2R1R2K1R1Label                matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ScriptFormatSwitch              matlab.ui.control.Switch
        RadiationPatternPredictorPanel  matlab.ui.container.Panel
        qeValueEditFieldLabel           matlab.ui.control.Label
        qeValueEditField                matlab.ui.control.NumericEditField
        qfValueEditFieldLabel           matlab.ui.control.Label
        qfValueEditField                matlab.ui.control.NumericEditField
        PhiCutEditFieldLabel            matlab.ui.control.Label
        PhiCutEditField                 matlab.ui.control.NumericEditField
        CalculateButton_2               matlab.ui.control.Button
    end


    properties (Access = private)
        NMat;
        NsMat;
        NiMat;
        HDRatio;
        ProcessedReport;
        Element;
        ElementAngle;
        ElementCat;
        ElementDim1D;
        ElementCoord;
        ElementDim2D;
        qvalue = 7;
        FDtheta = (-pi):0.05:(pi);
        % Description
    end
    
    methods (Access = private)
        
        function FDPlot(app)
            ElemWidth = app.ElementWidthmmEditField.Value;
            NumElem = app.NumofElementEditField.Value;
            %F/D Code
            %Spillover Efficiency
            app.HDRatio = 0.1:0.01:2;
            HDLength = length(app.HDRatio);
            app.NsMat = zeros(1,HDLength);
            D = ElemWidth*NumElem;
            q = app.qvalue;
            Id = (2*pi)/(2*q + 1);
            %Square
            for i = 1:1:HDLength
                HDR = app.HDRatio(i);
                H = HDR*D;
                r0 = H;
                Pr = @(x,y) (H./((sqrt(x.^2+y.^2+H^2)).^3)).*(((r0^2+(sqrt(x.^2+y.^2+H^2)).^2-(sqrt(x.^2+y.^2)).^2)./(2*r0*(sqrt(x.^2+y.^2+H^2)))).^(2*q));
                In = integral2(Pr,-(D/2),(D/2),-(D/2),(D/2),'Method','iterated');
                Ns = In/Id;
                app.NsMat(i) = Ns;
            end
            %Illumination Efficiency
            %Square
            qe = 1;
            app.NiMat = zeros(1,HDLength);
            for i = 1:1:HDLength
                HDR = app.HDRatio(i);
                H = HDR*D;
                r0 = H;
                I_D = @(x,y) (((H^qe)./(sqrt(x.^2+y.^2+H^2).^(1+qe))).*((r0^2+(sqrt(x.^2+y.^2+H^2)).^2-(sqrt(x.^2+y.^2)).^2)./(2*r0.*(sqrt(x.^2+y.^2+H^2)))).^(q)).^2;
                Denominator = integral2(I_D,-(D/2),(D/2),-(D/2),(D/2),'Method','iterated');
                I_N = @(x,y) ((H^qe)./(sqrt(x.^2+y.^2+H^2).^(1+qe))).*((r0^2+(sqrt(x.^2+y.^2+H^2)).^2-(sqrt(x.^2+y.^2)).^2)./(2*r0.*(sqrt(x.^2+y.^2+H^2)))).^(q);
                Norminator = (abs(integral2(I_N,-(D/2),(D/2),-(D/2),(D/2),'Method','iterated')))^2;
                Aa = D*D;
                Ni = (1/Aa)*(Norminator/Denominator);
                app.NiMat(i) = Ni;
            end
            %Efficiency
            app.NMat = app.NsMat.*app.NiMat;
        end

        function RAApertureScript(app)
            OpFreq = app.FrequencyHzEditField.Value;
            NumElement1axis = app.NumofElementEditField.Value;
            ElementSize_mm = app.ElementWidthmmEditField.Value;
            Feedpos = [app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value]; %x,y,z
            Radius = ElementSize_mm*NumElement1axis/2;
            
            app.Element = zeros(NumElement1axis,NumElement1axis);
            TotalElement = NumElement1axis*NumElement1axis;
            app.ElementCoord = zeros(TotalElement,2);
            Counter = 1;
            XStart = -((floor(NumElement1axis/2))*ElementSize_mm); %1st Element
            YStart = ((floor(NumElement1axis/2))*ElementSize_mm);
            YSteps = 0;
            XSteps = 0;
            YCoord = 0;
            XCoord = 0;
            GridWidth = ElementSize_mm;
            Wavelength = 299792458/OpFreq;
            WaveNumber = (2*pi)/Wavelength; %K0
            PhaseConstant = 0;
            %PhaseConstant = 168*pi/180;
            %PhaseConstant = -84.01*pi/180;
            ElementNum = zeros(TotalElement,2);
            app.ElementAngle = zeros(TotalElement,1);
            
            %Direction of the beam
            %Since it is all perpendicular to plane hence all 0
            theta = app.ThetaEditField.Value * pi/180; %theta0
            phi = app.PhiEditField.Value * pi/180; %theta0
            
            for j = 1:1:NumElement1axis
                YCoord = YStart - YSteps;
                for i = 1:1:NumElement1axis
                    XCoord = XStart + XSteps;
                    DistFeed = sqrt((XCoord-Feedpos(1))^2 + (YCoord-Feedpos(2))^2 + (0-Feedpos(3))^2);
                    DistFeed = DistFeed*0.001; %Ri in meter
                    PhaseRA = WaveNumber * (DistFeed - sin(theta) * (XCoord*0.001*cos(phi) + YCoord*0.001*sin(phi))) + PhaseConstant;
                    PhaseRA = PhaseRA*(180/pi); %Convert to degree
                    app.Element(j,i) = PhaseRA;
                    app.ElementCoord(Counter,1) = XCoord;
                    app.ElementCoord(Counter,2) = YCoord;
                    app.ElementAngle(Counter,1) = acos((Feedpos(3)*0.001)/DistFeed)*180/pi;
                    ElementNum(Counter,1) =i ;
                    ElementNum(Counter,2) =j ;
                    Counter = Counter + 1;
                    XSteps = XSteps + GridWidth;
                end
                XSteps = 0;
                YSteps = YSteps + GridWidth;
            end
            PhaseConstant = min(min(app.Element));
            app.Element = app.Element - PhaseConstant;
            app.Element = rem(app.Element, 360);
            
        end
        
        function ThetaPhaseCat(app)
            Counter = 1;
            NumElement1axis = app.NumofElementEditField.Value;
            app.ElementCat = zeros(NumElement1axis,NumElement1axis);      
            for j = 1:1:NumElement1axis
                for i = 1:1:NumElement1axis
                    if app.ElementAngle(Counter,1) > 60
                        app.ElementCat(j,i) = 7;
                    elseif app.ElementAngle(Counter,1) > 50
                        app.ElementCat(j,i) = 6;
                    elseif app.ElementAngle(Counter,1) > 40
                        app.ElementCat(j,i) = 5;
                    elseif app.ElementAngle(Counter,1) > 30
                        app.ElementCat(j,i) = 4;
                    elseif app.ElementAngle(Counter,1) > 20
                        app.ElementCat(j,i) = 3;
                    elseif app.ElementAngle(Counter,1) > 10
                        app.ElementCat(j,i) = 2;
                    else
                        app.ElementCat(j,i) = 1;
                    end
                    Counter = Counter + 1;
                end
            end
        end
        
        
        function Mapping(app)
            Counter = 1;
            UnitPhase = app.ProcessedReport;
            Dim = UnitPhase(:,1);
            [~,Numplot] = size(UnitPhase);
            for i = 2:1:Numplot
                DataTheta(:,i-1) = UnitPhase(:,i) - min(UnitPhase(:,i));
            end
            
            NumElement1axis = app.NumofElementEditField.Value;
            ElementDim = zeros(NumElement1axis,NumElement1axis);
            DistributionData = app.Element;
            
            for j = 1:1:NumElement1axis
                for i = 1:1:NumElement1axis
                    if app.ElementAngle(Counter,1) > 60
                        Dimension = spline(DataTheta(:,7),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    elseif app.ElementAngle(Counter,1) > 50
                        Dimension = spline(DataTheta(:,6),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    elseif app.ElementAngle(Counter,1) > 40
                        Dimension = spline(DataTheta(:,5),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    elseif app.ElementAngle(Counter,1) > 30
                        Dimension = spline(DataTheta(:,4),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    elseif app.ElementAngle(Counter,1) > 20
                        Dimension = spline(DataTheta(:,3),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    elseif app.ElementAngle(Counter,1) > 10
                        Dimension = spline(DataTheta(:,2),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    else
                        Dimension = spline(DataTheta(:,1),Dim,DistributionData(j,i));
                        if Dimension < min(Dim)
                            Dimension = min(Dim);
                        end
                        ElementDim(j,i) = Dimension;
                    end
                    Counter = Counter + 1;
                end
            end
            app.ElementDim2D = ElementDim;
            app.ElementDim1D = ElementDim(:);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            f = uifigure('Position',[300 300 400 120]);
            d = uiprogressdlg(f,'Title','Calculating',...
                'Indeterminate','on','Cancelable','on');
            FDPlot(app);
            RAApertureScript(app);
            ThetaPhaseCat(app);
            Mapping(app);
            
            %last line
            ReflectarrayCharacteristicsDropDownValueChanged(app, event)
            close(d);
            close(f);
            app.ReflectarrayCharacteristicsDropDown.Enable = 'on';
            app.GenerateButton.Enable = 'on';
            app.GenerateButton_2.Enable = 'on';
            app.GenerateButton_3.Enable = 'on';
        end

        % Value changed function: ReflectarrayCharacteristicsDropDown
        function ReflectarrayCharacteristicsDropDownValueChanged(app, event)
            ValueTypes = app.ReflectarrayCharacteristicsDropDown.Value;
            if ValueTypes == "Element Phase Shift"
                cla(app.UIAxes);
                hold(app.UIAxes);
                [~,Numplot] = size(app.ProcessedReport);
                for i=2:1:Numplot
                    plot(app.UIAxes,app.ProcessedReport(:,1),app.ProcessedReport(:,i));
                end
                xlabel(app.UIAxes,"Dimension (R1) / mm");
                ylabel(app.UIAxes,"Phase Shift（degree)");
                title(app.UIAxes,"Element Phase Shift");
                thetacount = 0;
                counter = 1;
                for i=2:1:Numplot
                    legendstr{counter} = sprintf('\\theta = %d°',thetacount);
                    thetacount = thetacount + 10;
                    counter = counter + 1;
                end
                legend(app.UIAxes,legendstr);
                colorbar(app.UIAxes,'hide');
                axis(app.UIAxes, 'tight');
                xticks(app.UIAxes,'auto');
                hold(app.UIAxes);
            elseif ValueTypes == "F/D Plot"
                f = uifigure('Position',[300 300 400 120]);
                d = uiprogressdlg(f,'Title','Plotting F/D',...
                'Indeterminate','on','Cancelable','on');
                FDPlot(app);
                cla(app.UIAxes);
                hold(app.UIAxes);
                plot(app.UIAxes,app.HDRatio,app.NMat);
                plot(app.UIAxes,app.HDRatio,app.NsMat);
                plot(app.UIAxes,app.HDRatio,app.NiMat);
                legend(app.UIAxes,"{\eta}_{a}","{\eta}_{s}","{\eta}_{i}");
                xlabel(app.UIAxes,"F/D");
                ylabel(app.UIAxes,"Theorectical efficiency");
                title(app.UIAxes,"F/D Efficiency Plot");
                colorbar(app.UIAxes,'hide');
                axis(app.UIAxes, 'tight');
                xticks(app.UIAxes,'auto');
                hold(app.UIAxes);
                close(f);
                close(d);
            elseif ValueTypes == "Phase Distribution Plot"
                cla(app.UIAxes);
                hold(app.UIAxes);
                imagesc(app.UIAxes,app.Element);
                xlabel(app.UIAxes,'x-axis [element number]');
                ylabel(app.UIAxes,'y-axis [element number]');
                title(app.UIAxes,'Phase Distribution')
                colorbar(app.UIAxes);
                legend(app.UIAxes,'hide');
                axis(app.UIAxes, 'tight');
                xticks(app.UIAxes,'auto');
                hold(app.UIAxes);
            elseif ValueTypes == "Element Theta Groups"
                cla(app.UIAxes);
                hold(app.UIAxes);
                imagesc(app.UIAxes,app.ElementCat);
                xlabel(app.UIAxes,'x-axis [element number]');
                ylabel(app.UIAxes,'y-axis [element number]');
                title(app.UIAxes,'Elements Theta Grouping')
                colorbar(app.UIAxes);
                legend(app.UIAxes,'hide');
                axis(app.UIAxes, 'tight');
                xticks(app.UIAxes,'auto');
                hold(app.UIAxes);
            elseif ValueTypes == "Dimension Mapping Plot"
                cla(app.UIAxes);
                hold(app.UIAxes);
                imagesc(app.UIAxes,app.ElementDim2D);
                xlabel(app.UIAxes,'x-axis [element number]');
                ylabel(app.UIAxes,'y-axis [element number]');
                title(app.UIAxes,'Dimension Mapping')
                colorbar(app.UIAxes);
                legend(app.UIAxes,'hide');
                axis(app.UIAxes, 'tight');
                xticks(app.UIAxes,'auto');
                hold(app.UIAxes);
            elseif ValueTypes == "Q Value Reference"
                cla(app.UIAxes);
                hold(app.UIAxes);
                q = app.qvalue;
                Uf = (cos(app.FDtheta)).^(2*q);
                Uf = 10*log10(Uf);
                Degree = app.FDtheta*180/pi;
                plot(app.UIAxes,Degree,Uf);
                xlabel(app.UIAxes,'Theta / Degree');
                ylabel(app.UIAxes,'Normalized Gain');
                title(app.UIAxes,'Theorectical Feed Model')
                colorbar(app.UIAxes,'hide');
                legend(app.UIAxes,'hide');
                xticks(app.UIAxes,(-100:10:100));
                axis(app.UIAxes,[-100 100 -10 0]);
                hold(app.UIAxes);
            end
        end

        % Button pushed function: ElementPhasePathButton
        function ElementPhasePathButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            Directory = strcat(path,file);
            UnitPhase = csvread(Directory);
            [width,length]= size(UnitPhase);
            ProcessedPhase = UnitPhase(:,2:length);
            for i = 1:1:length-1
                flag = 0; % 0 = negative, 1 = positive
                preflag = 0;
                unwrapflag = 0;
                for j=1:1:width
                    if ProcessedPhase(j,i) < 0
                        flag = 0;
                    elseif ProcessedPhase(j,i) > 0
                        flag = 1;
                    end
                    if preflag == 0 && flag == 1
                        unwrapflag = unwrapflag + 1;
                    end
                    if unwrapflag > 0
                        ProcessedPhase(j,i) = ProcessedPhase(j,i) - unwrapflag*360;
                    end
                    preflag = flag;
                end
            end           
            ProcessedPhase = ProcessedPhase(:,:) - ProcessedPhase(1,:);
            app.ProcessedReport = cat(2,UnitPhase(:,1),ProcessedPhase);
            app.DesignButton.Enable = 'on';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Freq = app.FrequencyEditField.Value;
            GaindB = app.GaindBEditField.Value;
            Area = app.AreaEditField.Value;
            Gain = 10^(GaindB/10);
            EffAperture = ((299792458^2)/(Freq^2))*(Gain/(4*pi));
            ApertureEffiency = EffAperture/Area;
            app.ApertureEfficiencyEditField.Value = ApertureEffiency;
        end

        % Button pushed function: FeedLibraryButton
        function FeedLibraryButtonPushed(app, event)
            web('https://drive.google.com/open?id=1mIXkcydgqQGUOPss1dMZ6OKrM_xcRVZq','-browser');
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            mkdir('OneStopRA/DoubleCircularRing');
            Dimension = app.ElementDim1D;
            Coordinates = app.ElementCoord;
            K1 = app.K1EditField.Value;
            K2 = app.K2EditField.Value;
            NumElement1axis = app.NumofElementEditField.Value;
            
            TotalElement = NumElement1axis*NumElement1axis;
            Radius = NumElement1axis*app.ElementWidthmmEditField.Value/2;
            f = uifigure('Position',[300 300 400 120]);
            d = uiprogressdlg(f,'Title','Scripting in Progress',...
                'Indeterminate','on','Cancelable','on');
            
            if app.ScriptFormatSwitch.Value == "DXF"
                Script = fopen('OneStopRA/DoubleCircularRing/DoubleCircular.scr','w');
                W = K2*(Dimension(:,1));
                OuterRingInner = 2*(Dimension(:,1)-W);
                OuterRingOuter = 2*(Dimension(:,1));
                InnerRingOuter = 2*K1*(Dimension(:,1));
                InnerRingInner = 2*(K1*(Dimension(:,1))-W);
                
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,InnerRingInner);
                Report = cat(2,Report,InnerRingOuter);
                Report = cat(2,Report,OuterRingInner);
                Report = cat(2,Report,OuterRingOuter);
                
                for i = 1:1:TotalElement
                    
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    
                    fprintf(Script,'DONUT\n');
                    fprintf(Script,'%f\n',Report(i,4));
                    fprintf(Script,'%f\n',Report(i,5));
                    fprintf(Script,'%f,',Report(i,1));
                    fprintf(Script,'%f\n',Report(i,2));
                    fprintf(Script,'\n');
                    
                    fprintf(Script,'DONUT\n');
                    fprintf(Script,'%f\n',Report(i,6));
                    fprintf(Script,'%f\n',Report(i,7));
                    fprintf(Script,'%f,',Report(i,1));
                    fprintf(Script,'%f\n',Report(i,2));
                    fprintf(Script,'\n');
                end
                
                 fclose(Script);
                               
            elseif app.ScriptFormatSwitch.Value == "MCR"
                Script = fopen('OneStopRA/DoubleCircularRing/DoubleCircular.mcr','w');
                fprintf(Script,'Sub Main ()\n');
                fprintf(Script,'Component.New "Ring"\n');
                W = K2*(Dimension(:,1));
                OuterRingInner = (Dimension(:,1)-W);
                OuterRingOuter = (Dimension(:,1));
                InnerRingOuter = K1*(Dimension(:,1));
                InnerRingInner = (K1*(Dimension(:,1))-W);
                
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,InnerRingInner);
                Report = cat(2,Report,InnerRingOuter);
                Report = cat(2,Report,OuterRingInner);
                Report = cat(2,Report,OuterRingOuter);
                
                for i = 1:1:TotalElement
                    
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    fprintf(Script,'With Cylinder\n');
                    fprintf(Script,'     .Reset\n');
                    fprintf(Script,'     .Name "Outer %d"\n',i);
                    fprintf(Script,'     .Component "Ring"\n');
                    fprintf(Script,'     .Material "PEC"\n');
                    fprintf(Script,'     .OuterRadius "%f"\n',0.5*Report(i,7));
                    fprintf(Script,'     .InnerRadius "%f"\n',0.5*Report(i,6));
                    fprintf(Script,'     .Axis "z"\n');
                    fprintf(Script,'     .Zrange "0", "0"\n');
                    fprintf(Script,'     .Xcenter "%f"\n',0.5*Report(i,1));
                    fprintf(Script,'     .Ycenter "%f"\n',0.5*Report(i,2));
                    fprintf(Script,'     .Segments "0"\n');
                    fprintf(Script,'     .Create\n');
                    fprintf(Script,'End With\n');
                    
                end
                
                for i = 1:1:TotalElement
                    
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    fprintf(Script,'With Cylinder\n');
                    fprintf(Script,'     .Reset\n');
                    fprintf(Script,'     .Name "Inner %d"\n',i);
                    fprintf(Script,'     .Component "Ring"\n');
                    fprintf(Script,'     .Material "PEC"\n');
                    fprintf(Script,'     .OuterRadius "%f"\n',0.5*Report(i,5));
                    fprintf(Script,'     .InnerRadius "%f"\n',0.5*Report(i,4));
                    fprintf(Script,'     .Axis "z"\n');
                    fprintf(Script,'     .Zrange "0", "0"\n');
                    fprintf(Script,'     .Xcenter "%f"\n',0.5*Report(i,1));
                    fprintf(Script,'     .Ycenter "%f"\n',0.5*Report(i,2));
                    fprintf(Script,'     .Segments "0"\n');
                    fprintf(Script,'     .Create\n');
                    fprintf(Script,'End With\n');
                    
                end          
                fprintf(Script,'End Sub');
                fclose(Script);
            end    
                close(d);
                close(f);   
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            f = uifigure('Position',[300 300 400 120]);
            d = uiprogressdlg(f,'Title','Scripting in Progress',...
                'Indeterminate','on','Cancelable','on');
            mkdir('OneStopRA/SquarePatch');
            Dimension = app.ElementDim1D;
            Coordinates = app.ElementCoord;
            NumElement1axis = app.NumofElementEditField.Value;
            TotalElement = NumElement1axis*NumElement1axis;
            Radius = NumElement1axis*app.ElementWidthmmEditField.Value/2;
            
            %Choose Your Chosen Parameters
            if app.ScriptFormatSwitch.Value == "DXF"
                Left = Coordinates(:,2) - Dimension/2;
                Top = Coordinates(:,1) + Dimension/2;
                Right = Coordinates(:,2) + Dimension/2;
                Bottom = Coordinates(:,1) - Dimension/2;
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,Left);
                Report = cat(2,Report,Top);
                Report = cat(2,Report,Right);
                Report = cat(2,Report,Bottom);
                Script = fopen('OneStopRA/SquarePatch/SquarePatch.scr','w');
                fprintf(Script,'RECTANGLE\n');
                
                for i = 1:1:TotalElement
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    
                    fprintf(Script,'%f,',Report(i,4));
                    fprintf(Script,'%f\n',Report(i,5));
                    fprintf(Script,'%f,',Report(i,6));
                    fprintf(Script,'%f\n',Report(i,7));
                    fprintf(Script,'\n');
                end
                
                fclose(Script);
            end
            
            if app.ScriptFormatSwitch.Value == "MCR"
                Left = Coordinates(:,2) - Dimension/2;
                Top = Coordinates(:,1) + Dimension/2;
                Right = Coordinates(:,2) + Dimension/2;
                Bottom = Coordinates(:,1) - Dimension/2;
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,Left);
                Report = cat(2,Report,Top);
                Report = cat(2,Report,Right);
                Report = cat(2,Report,Bottom);
                
                %Set Radius
                Script = fopen('OneStopRA/SquarePatch/SquarePatch.mcr','w');
                fprintf(Script,'Sub Main ()\n');
                fprintf(Script,'Component.New "Array1"\n');
                
                for i = 1:1:TotalElement
                    
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    fprintf(Script,'With Brick\n');
                    fprintf(Script,'     .Reset\n');
                    fprintf(Script,'     .Name "Patch%d"\n',i);
                    fprintf(Script,'     .Component "Array1"\n');
                    fprintf(Script,'     .Material "PEC"\n');
                    fprintf(Script,'     .Xrange "%f", "%f"\n',Report(i,4),Report(i,6));
                    fprintf(Script,'     .Yrange "%f", "%f"\n',Report(i,7),Report(i,5));
                    fprintf(Script,'     .Zrange "0", "0"\n');
                    fprintf(Script,'     .Create\n');
                    fprintf(Script,'End With\n');
                    
                end
                
                fprintf(Script,'End Sub');
                fclose(Script);
            end
            
            close(d);
            close(f);            
        end

        % Button pushed function: GenerateButton_3
        function GenerateButton_3Pushed(app, event)
            f = uifigure('Position',[300 300 400 120]);
            d = uiprogressdlg(f,'Title','Scripting in Progress',...
                'Indeterminate','on','Cancelable','on');
            mkdir('OneStopRA/SquarePatch2Layers');
            
            Dimension = app.ElementDim1D;
            Coordinates = app.ElementCoord;
            NumElement1axis = app.NumofElementEditField.Value;
            TotalElement = NumElement1axis*NumElement1axis;
            Radius = NumElement1axis*app.ElementWidthmmEditField.Value/2;
            
            if app.ScriptFormatSwitch.Value == "DXF"
                %Choose Your Chosen Parameters
                Left = Coordinates(:,2) - Dimension/2;
                Top = Coordinates(:,1) + Dimension/2;
                Right = Coordinates(:,2) + Dimension/2;
                Bottom = Coordinates(:,1) - Dimension/2;
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,Left);
                Report = cat(2,Report,Top);
                Report = cat(2,Report,Right);
                Report = cat(2,Report,Bottom);
                Script = fopen('OneStopRA/SquarePatch2Layers/Bottom.scr','w');
                fprintf(Script,'RECTANGLE\n');
                
                for i = 1:1:TotalElement
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    
                    fprintf(Script,'%f,',Report(i,4));
                    fprintf(Script,'%f\n',Report(i,5));
                    fprintf(Script,'%f,',Report(i,6));
                    fprintf(Script,'%f\n',Report(i,7));
                    fprintf(Script,'\n');
                end
                
                fclose(Script);
                
                Dimension = app.ElementDim1D;
                Coordinates = app.ElementCoord;
                NumElement1axis = app.NumofElementEditField.Value;
                TotalElement = NumElement1axis*NumElement1axis;
                Radius = NumElement1axis*app.ElementWidthmmEditField.Value/2;
                K1 = app.K1EditField_2.Value;
                
                %Choose Your Chosen Parameters
                Left = Coordinates(:,2) - (Dimension*K1)/2;
                Top = Coordinates(:,1) + (Dimension*K1)/2;
                Right = Coordinates(:,2) + (Dimension*K1)/2;
                Bottom = Coordinates(:,1) - (Dimension*K1)/2;
                Report = cat(2,Coordinates,(Dimension*K1));
                Report = cat(2,Report,Left);
                Report = cat(2,Report,Top);
                Report = cat(2,Report,Right);
                Report = cat(2,Report,Bottom);
                Script = fopen('OneStopRA/SquarePatch2Layers/Top.scr','w');
                fprintf(Script,'RECTANGLE\n');
                
                for i = 1:1:TotalElement
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    
                    fprintf(Script,'%f,',Report(i,4));
                    fprintf(Script,'%f\n',Report(i,5));
                    fprintf(Script,'%f,',Report(i,6));
                    fprintf(Script,'%f\n',Report(i,7));
                    fprintf(Script,'\n');
                end
                
                fclose(Script);
                
            end
            
            if app.ScriptFormatSwitch.Value == "MCR"
                
                Left = Coordinates(:,2) - Dimension/2;
                Top = Coordinates(:,1) + Dimension/2;
                Right = Coordinates(:,2) + Dimension/2;
                Bottom = Coordinates(:,1) - Dimension/2;
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,Left);
                Report = cat(2,Report,Top);
                Report = cat(2,Report,Right);
                Report = cat(2,Report,Bottom);
                
                %Set Radius
                Script = fopen('OneStopRA/SquarePatch2Layers/2LayersStack.mcr','w');
                fprintf(Script,'Sub Main ()\n');
                fprintf(Script,'Component.New "BottomLayer"\n');
                
                for i = 1:1:TotalElement
                    
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    fprintf(Script,'With Brick\n');
                    fprintf(Script,'     .Reset\n');
                    fprintf(Script,'     .Name "Patch%d"\n',i);
                    fprintf(Script,'     .Component "BottomLayer"\n');
                    fprintf(Script,'     .Material "PEC"\n');
                    fprintf(Script,'     .Xrange "%f", "%f"\n',Report(i,4),Report(i,6));
                    fprintf(Script,'     .Yrange "%f", "%f"\n',Report(i,7),Report(i,5));
                    fprintf(Script,'     .Zrange "0", "0"\n');
                    fprintf(Script,'     .Create\n');
                    fprintf(Script,'End With\n');
                    
                end
                
                K1 = app.K1EditField_2.Value;
                Left = Coordinates(:,2) - Dimension*K1/2;
                Top = Coordinates(:,1) + Dimension*K1/2;
                Right = Coordinates(:,2) + Dimension*K1/2;
                Bottom = Coordinates(:,1) - Dimension*K1/2;
                Report = cat(2,Coordinates,Dimension);
                Report = cat(2,Report,Left);
                Report = cat(2,Report,Top);
                Report = cat(2,Report,Right);
                Report = cat(2,Report,Bottom);
                
                %Set Radius
                fprintf(Script,'Component.New "TopLayer"\n');
                
                for i = 1:1:TotalElement
                    
                    xi = Report(i,1);
                    yi = Report(i,2);
                    ElementDist = sqrt(xi^2 + yi^2);
                    
                    if ElementDist > Radius
                        continue;
                    end
                    fprintf(Script,'With Brick\n');
                    fprintf(Script,'     .Reset\n');
                    fprintf(Script,'     .Name "Patch%d"\n',i);
                    fprintf(Script,'     .Component "TopLayer"\n');
                    fprintf(Script,'     .Material "PEC"\n');
                    fprintf(Script,'     .Xrange "%f", "%f"\n',Report(i,4),Report(i,6));
                    fprintf(Script,'     .Yrange "%f", "%f"\n',Report(i,7),Report(i,5));
                    fprintf(Script,'     .Zrange "0", "0"\n');
                    fprintf(Script,'     .Create\n');
                    fprintf(Script,'End With\n');
                    
                end
                
                fprintf(Script,'End Sub');
                fclose(Script);
                
            end
            
            
            close(d);
            close(f);            
        end

        % Button pushed function: ElementLibraryButton
        function ElementLibraryButtonPushed(app, event)
            web('https://drive.google.com/open?id=1rW4Te45Gv2-WM_6Dx5mG8dRTbPnT-EWN','-browser');   
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f = uifigure;
            message = sprintf('Welcome to One-Stop Reflectarray!\n 1.Please select element phase report XXX.csv (please follow the format of the demo example)\n 2.Please input reflectarray and feed parameters\n 3.Press Design\n 4.Choose the correct element type and press Generate for scripts');
            uialert(f,message,'Help','Icon','info');
        end

        % Value changed function: qValueSlider
        function qValueSliderValueChanged(app, event)
            value = app.qValueSlider.Value;
            app.qvalue = value;
            ValueTypes = app.ReflectarrayCharacteristicsDropDown.Value;
            if ValueTypes == "F/D Plot"
                f = uifigure('Position',[300 300 400 120]);
                d = uiprogressdlg(f,'Title','Plotting F/D',...
                'Indeterminate','on','Cancelable','on');
                FDPlot(app);
                cla(app.UIAxes);
                hold(app.UIAxes);
                plot(app.UIAxes,app.HDRatio,app.NMat);
                plot(app.UIAxes,app.HDRatio,app.NsMat);
                plot(app.UIAxes,app.HDRatio,app.NiMat);
                legend(app.UIAxes,"{\eta}_{a}","{\eta}_{s}","{\eta}_{i}");
                xlabel(app.UIAxes,"F/D");
                ylabel(app.UIAxes,"Theorectical efficiency");
                title(app.UIAxes,"F/D Efficiency Plot");
                colorbar(app.UIAxes,'hide');
                axis(app.UIAxes, 'tight');
                xticks(app.UIAxes,'auto');
                hold(app.UIAxes);
                close(f);
                close(d);
            end
        end

        % Value changing function: qValueSlider
        function qValueSliderValueChanging(app, event)
            changingValue = event.Value;
            ValueTypes = app.ReflectarrayCharacteristicsDropDown.Value;
            if ValueTypes == "Q Value Reference"
                cla(app.UIAxes);
                hold(app.UIAxes);
                q = changingValue;
                Uf = (cos(app.FDtheta)).^(2*q);
                Uf = 10*log10(Uf);
                Degree = app.FDtheta.*180/pi;
                plot(app.UIAxes,Degree,Uf);
                xlabel(app.UIAxes,'Theta / Degree');
                ylabel(app.UIAxes,'Normalized Gain');
                title(app.UIAxes,'Theorectical Feed Model')
                colorbar(app.UIAxes,'hide');
                legend(app.UIAxes,'hide');
                xticks(app.UIAxes,(-100:10:100));
                axis(app.UIAxes,[-100 100 -10 0]);
                hold(app.UIAxes);
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            f = uifigure('Position',[300 300 400 120]);
            d = uiprogressdlg(f,'Title','Calculating',...
                'Indeterminate','on','Cancelable','on');
            fig = uifigure;
            ax = uiaxes(fig,'Position',[10 10 550 400]);
            GridSize = app.ElementWidthmmEditField.Value*1e-3;
            NumElement = app.NumofElementEditField.Value;
            xPos = app.XEditField.Value*1e-3;
            yPos = app.YEditField.Value*1e-3;
            zPos = app.ZEditField.Value*1e-3;
            Feedpos = [xPos,yPos,zPos]; %X Y Z
            Radius = NumElement*GridSize/2;
            %Initiate
            YSteps = 0;
            XSteps = 0;
            %Reflectarray Parameters
            OpFreq = app.FrequencyHzEditField.Value;
            Wavelength = 299792458/OpFreq;
            k = (2*pi)/Wavelength; %K0
            theta_b = app.ThetaEditField.Value * pi/180; %theta0
            phi_b = app.PhiEditField.Value * pi/180;
            qf = app.qfValueEditField.Value * 2;
            qe = app.qeValueEditField.Value;
            Phi = app.PhiCutEditField.Value * pi/180;
            Counter_Temp = 0;
            
            for Theta = -1.5:0.001:1.5
                XStart = -((floor(NumElement/2))*GridSize);
                YStart = (floor(NumElement/2))*GridSize;
                YSteps = 0;
                EFieldSum = 0;
                Counter_Temp = Counter_Temp + 1;
                for j = 1:1:NumElement
                    YCoord = YStart - YSteps; %Coord = coordinate of mnth element
                    for i = 1:1:NumElement %This is where the real action is
                        XCoord = XStart + XSteps;
                        rfvec = Feedpos;
                        rmnvec = [XCoord,YCoord,0]; %Center = 0,0,0
                        rvec = rmnvec - rfvec;
                        FeedHeight = Feedpos(3);
                        Theta_e = acos(FeedHeight/norm(rvec));
                        Theta_f = acos(((norm(rfvec))^2 + (norm(rvec))^2 - (norm(rmnvec))^2)/(2*norm(rfvec)*norm(rvec)));
                        XSteps = XSteps + GridSize;
                        uvec = [sin(Theta)*cos(Phi), sin(Theta)*sin(Phi), cos(Theta)];
                        Phi_mn = k * (norm(rvec) - sin(theta_b) * (XCoord*cos(phi_b) + YCoord*sin(phi_b)));
                        if (XCoord^2 + YCoord^2)^0.5 > Radius
                            continue;
                        else
                            EField_1element = (((cos(Theta))^qe)*((cos(Theta_f))^qf)/norm(rvec))...
                                *exp(-sqrt(-1)*k*(norm(rvec)-dot(rmnvec,uvec)))...
                                *((cos(Theta_e))^qe)*exp(sqrt(-1)*Phi_mn);
                            EFieldSum = EFieldSum + EField_1element;
                        end
                    end
                    XSteps = 0;
                    YSteps = YSteps + GridSize;
                end
                EFieldTotal(Counter_Temp) = 10*log10(abs(EFieldSum));
            end
            theta_rad = -1.5:0.001:1.5;
            theta_deg = theta_rad*180/pi;
            plot(ax,theta_deg,EFieldTotal);
            set(ax,'xminorgrid','on','yminorgrid','on')
            xlabel(ax,'Theta / Degree');
            ylabel(ax,'Theorectical Gain');
            title(ax,'Predicted Radiation Pattern')
            colorbar(ax,'hide');
            legend(ax,'hide');
            axis(ax,'normal');
            close(f);
            close(d);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReflectarrayLaboratoryUIFigure = uifigure;
            app.ReflectarrayLaboratoryUIFigure.Name = 'Reflectarray Laboratory';
            app.ReflectarrayParametersPanel = uipanel(app.ReflectarrayLaboratoryUIFigure);
            app.ReflectarrayParametersPanel.Title = 'Reflectarray Parameters';
            app.FrequencyHzEditFieldLabel = uilabel(app.ReflectarrayParametersPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.ReflectarrayParametersPanel, 'numeric');
            app.FrequencyHzEditField.Value = 28000000000;
            app.ElementWidthmmEditFieldLabel = uilabel(app.ReflectarrayParametersPanel);
            app.ElementWidthmmEditFieldLabel.Text = 'Element Width (mm)';
            app.ElementWidthmmEditField = uieditfield(app.ReflectarrayParametersPanel, 'numeric');
            app.ElementWidthmmEditField.Value = 4.4;
            app.NumofElementEditFieldLabel = uilabel(app.ReflectarrayParametersPanel);
            app.NumofElementEditFieldLabel.Text = 'Num of Element';
            app.NumofElementEditField = uieditfield(app.ReflectarrayParametersPanel, 'numeric');
            app.NumofElementEditField.Value = 51;
            app.ElementPhasePathButton = uibutton(app.ReflectarrayParametersPanel, 'push');
            app.ElementPhasePathButton.ButtonPushedFcn = createCallbackFcn(app, @ElementPhasePathButtonPushed, true);
            app.ElementPhasePathButton.Text = 'Element Phase Path';
            app.ElementLibraryButton = uibutton(app.ReflectarrayParametersPanel, 'push');
            app.ElementLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @ElementLibraryButtonPushed, true);
            app.ElementLibraryButton.Text = 'Element Library';
            app.ThetaEditFieldLabel = uilabel(app.ReflectarrayParametersPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.ReflectarrayParametersPanel, 'numeric');
            app.PhiEditFieldLabel = uilabel(app.ReflectarrayParametersPanel);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.ReflectarrayParametersPanel, 'numeric');
            app.UIAxes = uiaxes(app.ReflectarrayLaboratoryUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReflectarrayCharacteristicsDropDownLabel = uilabel(app.ReflectarrayLaboratoryUIFigure);
            app.ReflectarrayCharacteristicsDropDownLabel.Text = 'Reflectarray Characteristics';
            app.ReflectarrayCharacteristicsDropDown = uidropdown(app.ReflectarrayLaboratoryUIFigure);
            app.ReflectarrayCharacteristicsDropDown.Items = {'Element Phase Shift', 'F/D Plot', 'Phase Distribution Plot', 'Element Theta Groups', 'Dimension Mapping Plot', 'Q Value Reference'};
            app.ReflectarrayCharacteristicsDropDown.ValueChangedFcn = createCallbackFcn(app, @ReflectarrayCharacteristicsDropDownValueChanged, true);
            app.ReflectarrayCharacteristicsDropDown.Enable = 'off';
            app.ReflectarrayCharacteristicsDropDown.Value = 'Element Phase Shift';
            app.DesignButton = uibutton(app.ReflectarrayLaboratoryUIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Enable = 'off';
            app.DesignButton.Text = 'Design';
            app.FeedParametersPanel = uipanel(app.ReflectarrayLaboratoryUIFigure);
            app.FeedParametersPanel.Title = 'Feed Parameters';
            app.XEditFieldLabel = uilabel(app.FeedParametersPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.FeedParametersPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.FeedParametersPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.FeedParametersPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.FeedParametersPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.FeedParametersPanel, 'numeric');
            app.ZEditField.Limits = [0 Inf];
            app.ZEditField.Value = 202;
            app.FeedLibraryButton = uibutton(app.FeedParametersPanel, 'push');
            app.FeedLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @FeedLibraryButtonPushed, true);
            app.FeedLibraryButton.Text = 'Feed Library';
            app.qValueSliderLabel = uilabel(app.FeedParametersPanel);
            app.qValueSliderLabel.Text = 'q Value';
            app.qValueSlider = uislider(app.FeedParametersPanel);
            app.qValueSlider.Limits = [0 20];
            app.qValueSlider.MajorTicks = [0 5 10 15 20];
            app.qValueSlider.ValueChangedFcn = createCallbackFcn(app, @qValueSliderValueChanged, true);
            app.qValueSlider.ValueChangingFcn = createCallbackFcn(app, @qValueSliderValueChanging, true);
            app.qValueSlider.Value = 7;
            app.ApertureEfficiencyCalculatorPanel = uipanel(app.ReflectarrayLaboratoryUIFigure);
            app.ApertureEfficiencyCalculatorPanel.Title = 'Aperture Efficiency Calculator';
            app.FrequencyEditFieldLabel = uilabel(app.ApertureEfficiencyCalculatorPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.ApertureEfficiencyCalculatorPanel, 'numeric');
            app.FrequencyEditField.Value = 28000000000;
            app.GaindBEditFieldLabel = uilabel(app.ApertureEfficiencyCalculatorPanel);
            app.GaindBEditFieldLabel.Text = 'Gain (dB)';
            app.GaindBEditField = uieditfield(app.ApertureEfficiencyCalculatorPanel, 'numeric');
            app.GaindBEditField.Value = 34.3;
            app.AreaEditFieldLabel = uilabel(app.ApertureEfficiencyCalculatorPanel);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.ApertureEfficiencyCalculatorPanel, 'numeric');
            app.AreaEditField.Value = 0.03954;
            app.CalculateButton = uibutton(app.ApertureEfficiencyCalculatorPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ApertureEfficiencyEditFieldLabel = uilabel(app.ApertureEfficiencyCalculatorPanel);
            app.ApertureEfficiencyEditFieldLabel.Text = 'Aperture Efficiency';
            app.ApertureEfficiencyEditField = uieditfield(app.ApertureEfficiencyCalculatorPanel, 'numeric');
            app.TabGroup = uitabgroup(app.ReflectarrayLaboratoryUIFigure);
            app.SquarePatchTab = uitab(app.TabGroup);
            app.SquarePatchTab.Title = 'Square Patch';
            app.GenerateButton_2 = uibutton(app.SquarePatchTab, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Enable = 'off';
            app.GenerateButton_2.Text = 'Generate';
            app.LayerSquareTab = uitab(app.TabGroup);
            app.LayerSquareTab.Title = '2-Layer Square';
            app.K1EditField_2Label = uilabel(app.LayerSquareTab);
            app.K1EditField_2Label.Text = 'K1';
            app.K1EditField_2 = uieditfield(app.LayerSquareTab, 'numeric');
            app.K1EditField_2.Value = 0.7;
            app.R2K1R1Label = uilabel(app.LayerSquareTab);
            app.R2K1R1Label.Text = 'R2 = K1*R1';
            app.GenerateButton_3 = uibutton(app.LayerSquareTab, 'push');
            app.GenerateButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_3Pushed, true);
            app.GenerateButton_3.Enable = 'off';
            app.GenerateButton_3.Text = 'Generate';
            app.DoubleCircularRingTab = uitab(app.TabGroup);
            app.DoubleCircularRingTab.Title = 'Double Circular Ring';
            app.K1EditFieldLabel = uilabel(app.DoubleCircularRingTab);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.DoubleCircularRingTab, 'numeric');
            app.K1EditField.Value = 0.6;
            app.K2EditFieldLabel = uilabel(app.DoubleCircularRingTab);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.DoubleCircularRingTab, 'numeric');
            app.K2EditField.Value = 0.2;
            app.WK2R1R2K1R1Label = uilabel(app.DoubleCircularRingTab);
            app.WK2R1R2K1R1Label.Text = 'W = K2*R1 , R2 = K1*R1';
            app.GenerateButton = uibutton(app.DoubleCircularRingTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.Button = uibutton(app.ReflectarrayLaboratoryUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.ScriptFormatSwitch = uiswitch(app.ReflectarrayLaboratoryUIFigure, 'slider');
            app.ScriptFormatSwitch.Items = {'DXF', 'MCR'};
            app.ScriptFormatSwitch.Value = 'DXF';
            app.RadiationPatternPredictorPanel = uipanel(app.ReflectarrayLaboratoryUIFigure);
            app.RadiationPatternPredictorPanel.Title = 'Radiation Pattern Predictor';
            app.qeValueEditFieldLabel = uilabel(app.RadiationPatternPredictorPanel);
            app.qeValueEditFieldLabel.Text = 'qe Value';
            app.qeValueEditField = uieditfield(app.RadiationPatternPredictorPanel, 'numeric');
            app.qeValueEditField.Value = 1;
            app.qfValueEditFieldLabel = uilabel(app.RadiationPatternPredictorPanel);
            app.qfValueEditFieldLabel.Text = 'qf Value';
            app.qfValueEditField = uieditfield(app.RadiationPatternPredictorPanel, 'numeric');
            app.qfValueEditField.Value = 6.4;
            app.PhiCutEditFieldLabel = uilabel(app.RadiationPatternPredictorPanel);
            app.PhiCutEditFieldLabel.Text = 'Phi Cut';
            app.PhiCutEditField = uieditfield(app.RadiationPatternPredictorPanel, 'numeric');
            app.CalculateButton_2 = uibutton(app.RadiationPatternPredictorPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Reflectarray_LaboratoryPlus

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReflectarrayLaboratoryUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReflectarrayLaboratoryUIFigure)
        end
    end
end